classdef SmartCloudOptimization_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        LinearProgrammingTab           matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        SERVERLabel                    matlab.ui.control.Label
        WORKLOADLabel                  matlab.ui.control.Label
        UTILIZATIONLabel               matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        ServerCUtilizationLabel        matlab.ui.control.Label
        ServerBUtilizationLabel        matlab.ui.control.Label
        ServerAUtilizationLabel        matlab.ui.control.Label
        ServerCWorkloadLabel           matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ServerBWorkloadLabel           matlab.ui.control.Label
        ServerAWorkloadLabel           matlab.ui.control.Label
        MinimumOperatingCostLabel      matlab.ui.control.Label
        RunOptimizationButton          matlab.ui.control.Button
        TotalCloudWorkloadLabel        matlab.ui.control.Label
        SMARTCLOUDOPTIMIZATIONLabel    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        GameTheoryTab                  matlab.ui.container.Tab
        NashPayoffLabel                matlab.ui.control.Label
        NashEquilibriumLabel           matlab.ui.control.Label
        DominantStrategyLabel          matlab.ui.control.Label
        AnalyzeGameButton              matlab.ui.control.Button
        ProviderBPayoffsLabel          matlab.ui.control.Label
        ProviderAPayoffsLabel          matlab.ui.control.Label
        PayoffBTable                   matlab.ui.control.Table
        PayoffATable                   matlab.ui.control.Table
        GameUIAxes                     matlab.ui.control.UIAxes
        SensitivityAnalysisTab         matlab.ui.container.Tab
        ExportPNGButton                matlab.ui.control.Button
        ExportCSVButton                matlab.ui.control.Button
        SensitivityTable               matlab.ui.control.Table
        RunSensitivityButton           matlab.ui.control.Button
        DemandScenariosEditField       matlab.ui.control.EditField
        DemandScenariosEditFieldLabel  matlab.ui.control.Label
        AllocationSensitivityAxes      matlab.ui.control.UIAxes
        CostSensitivityAxes            matlab.ui.control.UIAxes
        SensitivityAnalysisTab_2       matlab.ui.container.Tab
        ExportPNGButton_2              matlab.ui.control.Button
        ExportCSVButton_2              matlab.ui.control.Button
        SensitivityTable_2             matlab.ui.control.Table
        RunSensitivityButton_2         matlab.ui.control.Button
        DemandScenariosEditField_2     matlab.ui.control.EditField
        EditFieldLabel_2               matlab.ui.control.Label
        AllocationSensitivityAxes_2    matlab.ui.control.UIAxes
        CostSensitivityAxes_2          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PayoffATable.Data = [40, 70; 20, 60];
            app.PayoffBTable.Data = [40, 20; 70, 60];
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)

            % STEP 1: Get workload entered by user
            totalDemand = app.EditField.Value;

            % STEP 2: Validate workload
            if isnan(totalDemand) || totalDemand < 0 || totalDemand > 240
                uialert(app.UIFigure, ...
                    'Enter workload between 0 and 240.', ...
                    'Invalid Workload');
                return;
            end

            % STEP 3: Define server parameters
            serverNames = {'Server A','Server B','Server C'};

            capacity = [100; 80; 60];

            unitCost = [4; 3; 2.5];

            % STEP 4: Formulate Linear Programming problem
            f = unitCost;

            A = eye(3);
            b = capacity;

            Aeq = [1 1 1];
            beq = totalDemand;

            lb = zeros(3,1);
            ub = [];

            % STEP 5: Solve optimization
            options = optimoptions('linprog', ...
                'Display','none');

            [x, minCost, exitflag] = linprog( ...
                f,A,b,Aeq,beq,lb,ub,options);

            % STEP 6: Check optimization result
            if exitflag <= 0
                uialert(app.UIFigure, ...
                    'Optimization could not find a solution.', ...
                    'Optimization Error');
                return;
            end

            % STEP 7: Update minimum operating cost
            app.Label.Text = ['₹' num2str(minCost,'%.2f')];

            % STEP 8: Update server workloads
            app.Label_3.Text = num2str(x(1),'%.2f');

            app.Label_2.Text = num2str(x(2),'%.2f');

            app.Label_4.Text = num2str(x(3),'%.2f');


            % STEP 8A: Calculate server utilization

            utilization = (x ./ capacity) * 100;

            % Update utilization labels

            app.ServerAUtilizationLabel.Text = ...
                sprintf('Server A: %.1f%%', utilization(1));

            app.ServerBUtilizationLabel.Text = ...
                sprintf('Server B: %.1f%%', utilization(2));

            app.ServerCUtilizationLabel.Text = ...
                sprintf('Server C: %.1f%%', utilization(3));


            % STEP 9: Update workload distribution graph
            cla(app.UIAxes);

            bar(app.UIAxes,x);

            title(app.UIAxes,'Optimal Server Workload Distribution');

            xlabel(app.UIAxes,'Cloud Servers');

            ylabel(app.UIAxes,'Allocated Workload');

            xticks(app.UIAxes,1:3);

            xticklabels(app.UIAxes,serverNames);

            grid(app.UIAxes,'on');

            % STEP 10: Display completion message
            uialert(app.UIFigure, ...
                'Cloud workload optimization completed successfully!', ...
                'Optimization Successful');
        end

        % Button pushed function: AnalyzeGameButton
        function AnalyzeGameButtonPushed(app, event)
            % 1. Read payoff matrices from the UI tables
            strategies = {'Low Price', 'High Price'};
            payoffA = app.PayoffATable.Data;
            payoffB = app.PayoffBTable.Data;

            % 2. Provider A best responses (check column by column)
            bestA = false(2, 2);
            for j = 1:2
                bestA(:, j) = (payoffA(:, j) == max(payoffA(:, j)));
            end

            % 3. Provider B best responses (check row by row)
            bestB = false(2, 2);
            for i = 1:2
                bestB(i, :) = (payoffB(i, :) == max(payoffB(i, :)));
            end

            % 4. Check strictly dominant strategies
            domA = 'None';
            for i = 1:2
                if all(bestA(i, :)) && sum(payoffA(i, 1) == payoffA(:, 1)) == 1 && sum(payoffA(i, 2) == payoffA(:, 2)) == 1
                    domA = strategies{i};
                end
            end

            domB = 'None';
            for j = 1:2
                if all(bestB(:, j)) && sum(payoffB(1, j) == payoffB(1, :)) == 1 && sum(payoffB(2, j) == payoffB(2, :)) == 1
                    domB = strategies{j};
                end
            end
            app.DominantStrategyLabel.Text = sprintf('Dominant Strategies: A = %s | B = %s', domA, domB);

            % 5. Pure-strategy Nash Equilibrium (intersection of best responses)
            nashMask = bestA & bestB;
            [rIdx, cIdx] = find(nashMask);

            if isempty(rIdx)
                app.NashEquilibriumLabel.Text = 'Nash Equilibrium: No Pure-Strategy Equilibrium';
                app.NashPayoffLabel.Text = 'Equilibrium Payoffs: N/A';
            else
                eqText = '';
                payoffText = '';
                for k = 1:length(rIdx)
                    r = rIdx(k);
                    c = cIdx(k);
                    eqText = [eqText, sprintf('(%s, %s) ', strategies{r}, strategies{c})];
                    payoffText = [payoffText, sprintf('(A=%g, B=%g) ', payoffA(r, c), payoffB(r, c))];
                end
                app.NashEquilibriumLabel.Text = ['Nash Equilibrium: ', strtrim(eqText)];
                app.NashPayoffLabel.Text = ['Equilibrium Payoffs: ', strtrim(payoffText)];
            end

            % 6. Plot payoff comparison bar chart
            cla(app.GameUIAxes);
            outcomes = {'Low / Low', 'Low / High', 'High / Low', 'High / High'};
            payoffPairs = [payoffA(1,1), payoffB(1,1); ...
                payoffA(1,2), payoffB(1,2); ...
                payoffA(2,1), payoffB(2,1); ...
                payoffA(2,2), payoffB(2,2)];

            bar(app.GameUIAxes, payoffPairs);
            title(app.GameUIAxes, 'Provider Payoff Comparison');
            xlabel(app.GameUIAxes, 'Strategy Pair (Provider A / Provider B)');
            ylabel(app.GameUIAxes, 'Payoff / Utility');
            xticks(app.GameUIAxes, 1:4);
            xticklabels(app.GameUIAxes, outcomes);
            legend(app.GameUIAxes, {'Provider A', 'Provider B'}, 'Location', 'best');
            grid(app.GameUIAxes, 'on');
        end

        % Button pushed function: RunSensitivityButton
        function RunSensitivityButtonPushed(app, event)
            % 1. Parse demand scenarios from the text input
            demands = str2num(app.DemandScenariosEditField.Value); %#ok<ST2NM>
            if isempty(demands)
                uialert(app.UIFigure, ...
                    'Enter valid numeric demand values separated by commas (e.g., 100, 140, 180, 220, 240, 250).', ...
                    'Invalid Input');
                return;
            end

            % 2. Define the same LP model parameters used in Tab 1
            capacity = [100; 80; 60];
            unitCost = [4; 3; 2.5];
            f = unitCost;
            A = eye(3);
            b = capacity;
            Aeq = [1 1 1];
            lb = zeros(3, 1);
            ub = [];
            options = optimoptions('linprog', 'Display', 'none');

            % 3. Prepare containers for table and plots
            numScenarios = length(demands);
            resultsCell = cell(numScenarios, 6);
            feasibleDemands = [];
            feasibleCosts = [];
            feasibleAllocations = [];

            % 4. Solve LP for each demand scenario
            for k = 1:numScenarios
                D = demands(k);

                if D < 0 || D > sum(capacity)
                    % Infeasible or negative demand
                    resultsCell(k, :) = {D, '-', '-', '-', '-', 'Infeasible'};
                else
                    [x, minCost, exitflag] = linprog(f, A, b, Aeq, D, lb, ub, options);
                    if exitflag > 0
                        resultsCell(k, :) = {D, round(x(1)), round(x(2)), round(x(3)), round(minCost), 'Optimal'};
                        feasibleDemands(end+1) = D; %#ok<AGROW>
                        feasibleCosts(end+1) = minCost; %#ok<AGROW>
                        feasibleAllocations(end+1, :) = x'; %#ok<AGROW>
                    else
                        resultsCell(k, :) = {D, '-', '-', '-', '-', 'Infeasible'};
                    end
                end
            end

            % 5. Update the Sensitivity Results Table
            app.SensitivityTable.Data = resultsCell;

            % 6. Plot Chart 1: Demand vs Minimum Operating Cost
            cla(app.CostSensitivityAxes);
            if ~isempty(feasibleDemands)
                plot(app.CostSensitivityAxes, feasibleDemands, feasibleCosts, '-o', 'LineWidth', 2, 'MarkerSize', 6);
                title(app.CostSensitivityAxes, 'Demand vs. Minimum Operating Cost');
                xlabel(app.CostSensitivityAxes, 'Total Workload Demand');
                ylabel(app.CostSensitivityAxes, 'Minimum Cost (₹)');
                grid(app.CostSensitivityAxes, 'on');
            end

            % 7. Plot Chart 2: Stacked Workload Distribution by Demand
            cla(app.AllocationSensitivityAxes);
            if ~isempty(feasibleDemands)
                bar(app.AllocationSensitivityAxes, feasibleDemands, feasibleAllocations, 'stacked');
                title(app.AllocationSensitivityAxes, 'Optimal Workload Distribution by Demand');
                xlabel(app.AllocationSensitivityAxes, 'Total Workload Demand');
                ylabel(app.AllocationSensitivityAxes, 'Allocated Workload');
                legend(app.AllocationSensitivityAxes, {'Server A', 'Server B', 'Server C'}, 'Location', 'northwest');
                grid(app.AllocationSensitivityAxes, 'on');
            end
        end

        % Button pushed function: ExportCSVButton
        function ExportCSVButtonPushed(app, event)
            % Check that sensitivity results exist before exporting
            if isempty(app.SensitivityTable.Data)
                uialert(app.UIFigure, ...
                    'Click "Run Sensitivity" first to generate results before exporting.', ...
                    'No Data to Export');
                return;
            end

            % Open file save dialog
            [file, path] = uiputfile('sensitivity_results.csv', 'Save Sensitivity Results as CSV');
            if isequal(file, 0) || isequal(path, 0)
                return; % User clicked Cancel
            end

            try
                headers = {'Demand', 'Server_A', 'Server_B', 'Server_C', 'Min_Cost_Rs', 'Status'};
                resultsTable = cell2table(app.SensitivityTable.Data, 'VariableNames', headers);
                writetable(resultsTable, fullfile(path, file));
                uialert(app.UIFigure, ...
                    ['Results exported successfully to: ', fullfile(path, file)], ...
                    'Export Successful', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end

        % Button pushed function: ExportPNGButton
        function ExportPNGButtonPushed(app, event)
            % Open file save dialog for PNG image
            [file, path] = uiputfile('cost_sensitivity_chart.png', 'Save Cost Sensitivity Chart as PNG');
            if isequal(file, 0) || isequal(path, 0)
                return; % User clicked Cancel
            end

            try
                exportgraphics(app.CostSensitivityAxes, fullfile(path, file), 'Resolution', 300);
                uialert(app.UIFigure, ...
                    ['Chart saved successfully to: ', fullfile(path, file)], ...
                    'Export Successful', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset workload input to baseline default
            app.EditField.Value = 180;

            % Reset cost, workload, and utilization displays
            app.Label.Text = '₹0';
            app.Label_3.Text = '0';
            app.Label_2.Text = '0';
            app.Label_4.Text = '0';
            app.ServerAUtilizationLabel.Text = 'Server A: -- %';
            app.ServerBUtilizationLabel.Text = 'Server B: -- %';
            app.ServerCUtilizationLabel.Text = 'Server C: -- %';

            % Clear workload bar chart
            cla(app.UIAxes);
            title(app.UIAxes, 'Optimal Server Workload Distribution');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LinearProgrammingTab = uitab(app.TabGroup);
            app.LinearProgrammingTab.Title = 'Linear Programming';
            app.Panel = uipanel(app.LinearProgrammingTab);
            app.Panel.Title = 'Smart Cloud Optimization System';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Server Workload Distribution')
            xlabel(app.UIAxes, 'Cloud Servers')
            ylabel(app.UIAxes, 'Allocated Workload')
            zlabel(app.UIAxes, 'Z')
            app.SMARTCLOUDOPTIMIZATIONLabel = uilabel(app.Panel);
            app.SMARTCLOUDOPTIMIZATIONLabel.Text = 'SMART CLOUD OPTIMIZATION';
            app.TotalCloudWorkloadLabel = uilabel(app.Panel);
            app.TotalCloudWorkloadLabel.Text = 'Total Cloud Workload:';
            app.RunOptimizationButton = uibutton(app.Panel, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.MinimumOperatingCostLabel = uilabel(app.Panel);
            app.MinimumOperatingCostLabel.Text = 'Minimum Operating Cost';
            app.ServerAWorkloadLabel = uilabel(app.Panel);
            app.ServerAWorkloadLabel.Text = 'Server A Workload';
            app.ServerBWorkloadLabel = uilabel(app.Panel);
            app.ServerBWorkloadLabel.Text = 'Server B Workload';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '₹710';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '60';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '60';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '60';
            app.ServerCWorkloadLabel = uilabel(app.Panel);
            app.ServerCWorkloadLabel.Text = 'Server C Workload';
            app.ServerAUtilizationLabel = uilabel(app.Panel);
            app.ServerAUtilizationLabel.Text = 'Server A: -- %';
            app.ServerBUtilizationLabel = uilabel(app.Panel);
            app.ServerBUtilizationLabel.Text = 'Server B: -- %';
            app.ServerCUtilizationLabel = uilabel(app.Panel);
            app.ServerCUtilizationLabel.Text = 'Server C: -- %';
            app.EditFieldLabel = uilabel(app.Panel);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Value = 180;
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UTILIZATIONLabel = uilabel(app.Panel);
            app.UTILIZATIONLabel.Text = 'UTILIZATION';
            app.WORKLOADLabel = uilabel(app.Panel);
            app.WORKLOADLabel.Text = 'WORKLOAD';
            app.SERVERLabel = uilabel(app.Panel);
            app.SERVERLabel.Text = 'SERVER';
            app.GameTheoryTab = uitab(app.TabGroup);
            app.GameTheoryTab.Title = 'Game Theory';
            app.GameUIAxes = uiaxes(app.GameTheoryTab);
            title(app.GameUIAxes, 'Provider Payoff Comparison')
            xlabel(app.GameUIAxes, 'Strategy Pair (Provider A / Provider B)')
            ylabel(app.GameUIAxes, 'Payoff / Utility')
            zlabel(app.GameUIAxes, 'Z')
            app.PayoffATable = uitable(app.GameTheoryTab);
            app.PayoffATable.ColumnName = {'Low Price'; 'High Price'};
            app.PayoffATable.RowName = {'Low Price'; 'High Price'};
            app.PayoffBTable = uitable(app.GameTheoryTab);
            app.PayoffBTable.ColumnName = {'Low Price'; 'High Price'};
            app.PayoffBTable.RowName = {'Low Price'; 'High Price'};
            app.ProviderAPayoffsLabel = uilabel(app.GameTheoryTab);
            app.ProviderAPayoffsLabel.Text = 'Provider A Payoffs';
            app.ProviderBPayoffsLabel = uilabel(app.GameTheoryTab);
            app.ProviderBPayoffsLabel.Text = 'Provider B Payoffs';
            app.AnalyzeGameButton = uibutton(app.GameTheoryTab, 'push');
            app.AnalyzeGameButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeGameButtonPushed, true);
            app.AnalyzeGameButton.Text = 'Analyze Game';
            app.DominantStrategyLabel = uilabel(app.GameTheoryTab);
            app.DominantStrategyLabel.Text = 'Dominant Strategies: --';
            app.NashEquilibriumLabel = uilabel(app.GameTheoryTab);
            app.NashEquilibriumLabel.Text = 'Nash Equilibrium: --';
            app.NashPayoffLabel = uilabel(app.GameTheoryTab);
            app.NashPayoffLabel.Text = 'Equilibrium Payoffs: --';
            app.SensitivityAnalysisTab = uitab(app.TabGroup);
            app.SensitivityAnalysisTab.Title = 'Sensitivity Analysis';
            app.CostSensitivityAxes = uiaxes(app.SensitivityAnalysisTab);
            title(app.CostSensitivityAxes, 'Demand vs. Minimum Operating Cost')
            xlabel(app.CostSensitivityAxes, 'Total Workload Demand')
            ylabel(app.CostSensitivityAxes, 'Minimum Cost (₹)')
            zlabel(app.CostSensitivityAxes, 'Z')
            app.AllocationSensitivityAxes = uiaxes(app.SensitivityAnalysisTab);
            title(app.AllocationSensitivityAxes, 'Optimal Workload Distribution by Demand')
            xlabel(app.AllocationSensitivityAxes, 'Total Workload Demand')
            ylabel(app.AllocationSensitivityAxes, 'Allocated Workload')
            zlabel(app.AllocationSensitivityAxes, 'Z')
            app.DemandScenariosEditFieldLabel = uilabel(app.SensitivityAnalysisTab);
            app.DemandScenariosEditFieldLabel.Text = 'Demand Scenarios:';
            app.DemandScenariosEditField = uieditfield(app.SensitivityAnalysisTab, 'text');
            app.DemandScenariosEditField.Value = '100, 140, 180, 220, 240, 250';
            app.RunSensitivityButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.RunSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @RunSensitivityButtonPushed, true);
            app.RunSensitivityButton.Text = 'Run Sensitivity';
            app.SensitivityTable = uitable(app.SensitivityAnalysisTab);
            app.SensitivityTable.ColumnName = {'Demand'; 'Server A'; 'Server B'; 'Server C'; 'Min Cost (₹)'; 'Status'};
            app.SensitivityTable.RowName = {};
            app.ExportCSVButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.ExportCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVButtonPushed, true);
            app.ExportCSVButton.Text = 'Export CSV';
            app.ExportPNGButton = uibutton(app.SensitivityAnalysisTab, 'push');
            app.ExportPNGButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPNGButtonPushed, true);
            app.ExportPNGButton.Text = 'Export Chart PNG';
            app.SensitivityAnalysisTab_2 = uitab(app.TabGroup);
            app.SensitivityAnalysisTab_2.Title = 'Sensitivity Analysis';
            app.CostSensitivityAxes_2 = uiaxes(app.SensitivityAnalysisTab_2);
            title(app.CostSensitivityAxes_2, 'Demand vs. Minimum Operating Cost')
            xlabel(app.CostSensitivityAxes_2, 'Total Workload Demand')
            ylabel(app.CostSensitivityAxes_2, 'Minimum Cost (₹)')
            zlabel(app.CostSensitivityAxes_2, 'Z')
            app.AllocationSensitivityAxes_2 = uiaxes(app.SensitivityAnalysisTab_2);
            title(app.AllocationSensitivityAxes_2, 'Optimal Workload Distribution by Demand')
            xlabel(app.AllocationSensitivityAxes_2, 'Total Workload Demand')
            ylabel(app.AllocationSensitivityAxes_2, 'Allocated Workload')
            zlabel(app.AllocationSensitivityAxes_2, 'Z')
            app.EditFieldLabel_2 = uilabel(app.SensitivityAnalysisTab_2);
            app.EditFieldLabel_2.Text = 'Demand Scenarios:';
            app.DemandScenariosEditField_2 = uieditfield(app.SensitivityAnalysisTab_2, 'text');
            app.DemandScenariosEditField_2.Value = '100, 140, 180, 220, 240, 250';
            app.RunSensitivityButton_2 = uibutton(app.SensitivityAnalysisTab_2, 'push');
            app.RunSensitivityButton_2.Text = 'Run Sensitivity';
            app.SensitivityTable_2 = uitable(app.SensitivityAnalysisTab_2);
            app.SensitivityTable_2.ColumnName = {'Demand'; 'Server A'; 'Server B'; 'Server C'; 'Min Cost (₹)'; 'Status'};
            app.SensitivityTable_2.RowName = {};
            app.ExportCSVButton_2 = uibutton(app.SensitivityAnalysisTab_2, 'push');
            app.ExportCSVButton_2.Text = 'Export CSV';
            app.ExportPNGButton_2 = uibutton(app.SensitivityAnalysisTab_2, 'push');
            app.ExportPNGButton_2.Text = 'Export Chart PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartCloudOptimization_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end